classdef NIRS_Digitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadProbeMenu                   matlab.ui.container.Menu
        SaveRegistrationMenu            matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        SaveDataButton                  matlab.ui.control.Button
        MovetonextCheckBox              matlab.ui.control.CheckBox
        ShowRawMeasurementPointsCheckBox  matlab.ui.control.CheckBox
        TabGroup                        matlab.ui.container.TabGroup
        DigitizingTab                   matlab.ui.container.Tab
        ResetButton_3                   matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DigitizeExtraFiducicialsButton  matlab.ui.control.StateButton
        FiducicalsTextArea_3            matlab.ui.control.TextArea
        DigitizeOptodesButton           matlab.ui.control.StateButton
        FiducicalsTextArea_2            matlab.ui.control.TextArea
        DigitizeHeadPointsButton        matlab.ui.control.StateButton
        FiducicalsTextArea              matlab.ui.control.TextArea
        RegistrationTab                 matlab.ui.container.Tab
        HeadmodelPanel                  matlab.ui.container.Panel
        AtlasDropDown                   matlab.ui.control.DropDown
        AtlasDropDownLabel              matlab.ui.control.Label
        ProbeRegistrationPanel          matlab.ui.container.Panel
        OptodeCapOffsetEditField        matlab.ui.control.NumericEditField
        OptodeCapOffsetEditFieldLabel   matlab.ui.control.Label
        PointsOnOptodeCapCheckBox       matlab.ui.control.CheckBox
        DonotrefineheadpointsCheckBox   matlab.ui.control.CheckBox
        DitigizerErrorDistanceEditField  matlab.ui.control.NumericEditField
        DitigizerErrorDistanceEditFieldLabel  matlab.ui.control.Label
        DonotrefinedigitizedpointsCheckBox  matlab.ui.control.CheckBox
        HeadSizePanel                   matlab.ui.container.Panel
        LPACZRPAEditField               matlab.ui.control.NumericEditField
        LPACZRPAEditFieldLabel          matlab.ui.control.Label
        NasionCzInionEditField          matlab.ui.control.NumericEditField
        NasionCzInionEditFieldLabel     matlab.ui.control.Label
        UseMeasuredValueCheckBox        matlab.ui.control.CheckBox
        CircumfernceEditField           matlab.ui.control.NumericEditField
        CircumfernceEditFieldLabel      matlab.ui.control.Label
        PolhemusTab                     matlab.ui.container.Tab
        CommunicationPanel              matlab.ui.container.Panel
        NotConnectedLamp                matlab.ui.control.Lamp
        NotConnectedLampLabel           matlab.ui.control.Label
        COMPortDropDown                 matlab.ui.control.DropDown
        COMPortDropDownLabel            matlab.ui.control.Label
        FirmwareVersionEditField        matlab.ui.control.EditField
        FirmwareVersionEditFieldLabel   matlab.ui.control.Label
        DeviceIDEditField               matlab.ui.control.EditField
        DeviceIDEditFieldLabel          matlab.ui.control.Label
        HardwareSetupPanel              matlab.ui.container.Panel
        Sensor2DropDown                 matlab.ui.control.DropDown
        Sensor2DropDownLabel            matlab.ui.control.Label
        Sensor1DropDown                 matlab.ui.control.DropDown
        Sensor1DropDownLabel            matlab.ui.control.Label
        HemmisphereDropDown             matlab.ui.control.DropDown
        HemmisphereDropDownLabel        matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        COM=[]; % Serial Port 
        IsConnected=false;% Bool connected to Polhemus
        HeadPoints % Points digitized to estimate Nasipon/LPA/RPA
        OptodePoints % Optode Positions (as digitized)
        ExtraPoints % Extra Points to use for registration
        Atlas % Atlas Head model selected
        ProbeOriginal % Template probe
        ProbeRegistered % Registered Probe
        PtsRegistered;
        Tform;
        PtsRaw;

        TformFix;

    end
    
    methods (Access = public)
        
    end
    
    methods (Access = private)
        
        function results = ConnectPolhemus(app)
            results=false;
            try
                if(~isempty(app.COM))
                    try;
                        close(app.COM);
                    end
                end

                app.COM=serialport(app.COMPortDropDown.Value,115200,Parity="none");
                app.COM.configureTerminator('CR/LF','CR');
                writeline(app.COM,'X');
                pause(.1);
                if(app.COM.NumBytesAvailable==0)
                    return;
                end
                app.DeviceIDEditField.Value=readline(app.COM);

                % Set to ASCI Mode
                writeline(app.COM,'F0');
                % Set orientation
                writeline(app.COM,'G0,0,0');
                
                % Set The hemisphere
                writeline(app.COM,'H1,0,0,1');

%                 % Set the stylus function
%                 if(strcmp(app.Sensor1DropDown.Value,'Stylus') & strcmp(app.Sensor2DropDown.Value,'None'))
%                     writeline(app.COM,'L1,1');
%                     writeline(app.COM,[char(21) '1,1']);
%                     writeline(app.COM,[char(21) '2,0']);
%                     writeline(app.COM,'O1,3,5,8,1');
%                 elseif(strcmp(app.Sensor1DropDown.Value,'Stylus') & strcmp(app.Sensor2DropDown.Value,'Floating Sensor'))
%                      writeline(app.COM,'L1,1');
%                     writeline(app.COM,[char(21) '1,1']);
%                     writeline(app.COM,[char(21) '2,1']);
%                     writeline(app.COM,'O1,3,5');
%                     writeline(app.COM,'O2,3,8,1');
%                 elseif(strcmp(app.Sensor2DropDown.Value,'Stylus') & strcmp(app.Sensor1DropDown.Value,'None'))
%                     writeline(app.COM,'L2,1');
%                     writeline(app.COM,[char(21) '1,0']);
%                     writeline(app.COM,[char(21) '2,1']);
%                     writeline(app.COM,'O2,3,5,8,1');
%                 elseif(strcmp(app.Sensor2DropDown.Value,'Stylus') & strcmp(app.Sensor1DropDown.Value,'Floating Sensor'))
%                     writeline(app.COM,'L2,1');
%                     writeline(app.COM,[char(21) '1,1']);
%                     writeline(app.COM,[char(21) '2,1']);
%                     writeline(app.COM,'O1,3');
%                     writeline(app.COM,'O2,3,5,8,1');
%                 end
 
                writeline(app.COM,'L1,1');
                writeline(app.COM,'O1,3,5,8,1');
                writeline(app.COM,[char(21) '2,0']);
               writeline(app.COM,[char(21) '1,1']);
                
                %In centimeters
                writeline(app.COM,'U1');

                %Unboresight
                writeline(app.COM,[char(2) '1']); %ctrl-B
                writeline(app.COM,[char(2) '2']); %ctrl-B

                %reset frame
                writeline(app.COM,[char(18) '1']); %ctrl-R
                writeline(app.COM,[char(18) '2']); %ctrl-R


                results=true;
            end


        end

        function pp=applyTform(app,Pts)
            pp=Pts(:,1:3)*10;
            pp(:,4)=1;
            pp=pp*app.Tform;

            dist=sqrt(sum(pp(:,1:3).^2,2));
            if(mean(dist)>300)
                pp=Pts(:,1:3)*10;
                pp(:,4)=1;
                pp=pp*app.TformFix*app.Tform;
            end

        end

        function drawpoint(app,pt,color)
            if(nargin<3)
                color=[1 0 0];
            end
            if(norm(pt)>180)
                warning('ignoring out-of-bounds point');
                return;
            end


            axes(app.UIAxes);
            hold(app.UIAxes,'on');
            s=scatter3(app.UIAxes,pt(1),pt(2),pt(3));
            set(s,'LineWidth',2,'CData',color);
        end

        function registerprobe(app)
            
        end

        function registerhead(app)
            if(isempty(app.FiducicalsTextArea.UserData))
                return
            end
            HeadPts = app.FiducicalsTextArea.UserData(:,1:3)*10;
            k=clusterdata(HeadPts,3);

            pts(1,:)=mean(HeadPts(k==1,:),1);
            pts(2,:)=mean(HeadPts(k==2,:),1);
            pts(3,:)=mean(HeadPts(k==3,:),1);

            CZ=pts(find(pts(:,2)==max(pts(:,2))),:);
            LPA=pts(find(pts(:,1)==min(pts(:,1))),:);
            RPA=pts(find(pts(:,1)==max(pts(:,1))),:);
            NAS=[0 0 -13];
            COM=[RPA+LPA]/2;
            xyz2=[NAS; LPA; RPA; CZ; COM];

            mesh=app.Atlas;
            nasIdx=find(ismember(mesh(1).fiducials.Name,'spmnas'));
            lpaIdx=find(ismember(mesh(1).fiducials.Name,'spmlpa'));
            rpaIdx=find(ismember(mesh(1).fiducials.Name,'spmrpa'));
            CZIdx=find(ismember(mesh(1).fiducials.Name,'Cz'));
            xyz=[mesh(1).fiducials.X([nasIdx lpaIdx rpaIdx CZIdx]') ...
                mesh(1).fiducials.Y([nasIdx lpaIdx rpaIdx CZIdx]')...
                mesh(1).fiducials.Z([nasIdx lpaIdx rpaIdx CZIdx]')];
            xyz(5,:)=(xyz(2,:)+xyz(3,:))/2;
            xyz(:,4)=1;
            xyz2(:,4)=1;
            T=xyz2\xyz;
           
            
            if(~app.DonotrefineheadpointsCheckBox.Value)
                % Refine registration using ICP
                pts=app.FiducicalsTextArea_3.UserData*10;
                if(~isempty(pts))
                    pts(:,4)=1;
                    pts=pts*T;
                    pts=pts(:,1:3);
                    n=mesh(1).nodes;
                    [tr,tt]=icp(n',pts');
                    T2=[tr tt];
                    T2(4,4)=1;
                    T=T*T2';
                end
            end

            FIDpts=xyz2([1 2 3 4],:)*T;
            app.PtsRegistered.NAS=FIDpts(1,1:3);
            app.PtsRegistered.rpa=FIDpts(3,1:3);
            app.PtsRegistered.lpa=FIDpts(2,1:3);
            app.PtsRegistered.Cz=FIDpts(4,1:3);
            app.Tform=T;
            UpdateDraw(app);

            app.DigitizeOptodesButton.Enable="on";
            app.FiducicalsTextArea_2.Enable="on";
            app.DigitizeExtraFiducicialsButton.Enable="on";
            app.FiducicalsTextArea_3.Enable="on";
            
        end

        function UpdateDraw(app)
            axes(app.UIAxes);
            if(~isempty(app.Atlas))
                cla(app.UIAxes);
                app.Atlas(1).fiducials.Draw(:)=false;
                app.Atlas.draw([],[],[],[],app.UIAxes);
                axis(app.UIAxes,'off');
                 nirs.util.lightsurface(app.UIAxes);
            end
            if(~isempty(app.ProbeOriginal))
                cla(app.UIAxes_2);
                app.ProbeOriginal.draw1020(ones(height(app.ProbeOriginal.link),1)*[.7 .7 .7],...
                    repmat({'LineStyle', '-', 'LineWidth', 2}, [height(app.ProbeOriginal.link) 1]),app.UIAxes_2);
                hold(app.UIAxes,'on');
                app.ProbeOriginal.draw3d(ones(height(app.ProbeOriginal.link),1)*[.7 .7 .7],...
                    repmat({'LineStyle', '-', 'LineWidth', 2}, [height(app.ProbeOriginal.link) 1]),app.UIAxes);
                
            end
            if(~isempty(app.ProbeOriginal))
                hold(app.UIAxes_2,'on');
                app.ProbeRegistered.draw1020(ones(height(app.ProbeRegistered.link),1)*[0 0 0],...
                    repmat({'LineStyle', '-', 'LineWidth', 3}, [height(app.ProbeRegistered.link) 1]),app.UIAxes_2);  
                 hold(app.UIAxes,'on');
                 app.ProbeRegistered.draw3d(ones(height(app.ProbeRegistered.link),1)*[0 0 0],...
                    repmat({'LineStyle', '-', 'LineWidth', 3}, [height(app.ProbeRegistered.link) 1]),app.UIAxes);  
            end
            if(~isempty(app.PtsRegistered))
                hold(app.UIAxes,'on');
                flds=fields(app.PtsRegistered);
                for j=1:length(flds)
                    s(j)=scatter3(app.UIAxes,app.PtsRegistered.(flds{j})(1),...
                                app.PtsRegistered.(flds{j})(2),...
                                app.PtsRegistered.(flds{j})(3));
                end
                set(s,'LineWidth',5,'CData',[0 0 0]);
            end
            if(app.ShowRawMeasurementPointsCheckBox.Value)
                pts=app.FiducicalsTextArea_3.UserData;
                if(~isempty(pts))
                    for i=1:size(pts,1)
                        pp=app.applyTform(pts(i,:));
                        drawpoint(app,pp(1:3),[0 1 0]);
                    end
                end
                pts=app.FiducicalsTextArea_2.UserData;
                if(~isempty(pts))
                    for i=1:size(pts,1)
                        pp=app.applyTform(pts(i,:));
                        drawpoint(app,pp(1:3),[0 0 1]);
                    end
                end
            end

        end
        function [xyz,uvw] = getPoints(app)
            if(app.COM.NumBytesAvailable>0)
                line=readline(app.COM);
                tmp=sscanf(line,'%d %f %f %f %f %f %f %d');
                xyz=tmp(2:4);    
                uvw=tmp(5:7);
%                 if(strcmp(app.Sensor1DropDown.Value,'Stylus') & strcmp(app.Sensor2DropDown.Value,'None'))
%                     tmp=sscanf(line,'%d %f %f %f %f %f %f %d');
%                     xyz=tmp(2:4);    
%                     uvw=tmp(5:7);
%                 elseif(strcmp(app.Sensor1DropDown.Value,'Stylus') & strcmp(app.Sensor2DropDown.Value,'Floating Sensor'))
%                     tmp=sscanf(line,'%d %f %f %f %f %f %f %f %f %f %d');
%                     xyz=(tmp(2:4)-tmp(8:10));    
%                     uvw=tmp(5:7);
%                 elseif(strcmp(app.Sensor2DropDown.Value,'Stylus') & strcmp(app.Sensor1DropDown.Value,'None'))
%                     tmp=sscanf(line,'%d %f %f %f %f %f %f %d');
%                     xyz=tmp(2:4);    
%                     uvw=tmp(5:7);
%                 elseif(strcmp(app.Sensor2DropDown.Value,'Stylus') & strcmp(app.Sensor1DropDown.Value,'Floating Sensor'))
%                     tmp=sscanf(line,'%d %f %f %f %f %f %f %f %f %f %d');
%                     xyz=(tmp(5:7)-tmp(2:4));    
%                     uvw=tmp(8:10);
%                 end
            else
                xyz=[];
                uvw=[];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the Polehmus
            slist=serialportlist;
            if(isempty(slist))
                errordlg("I did not find any COM ports.  Check Windows Device Manager for LPT ports");
            end
            app.COMPortDropDown.Items=slist;
            app.COMPortDropDown.Value=slist{1};

            app.IsConnected = ConnectPolhemus(app);
            if(app.IsConnected)
                app.NotConnectedLampLabel.Text='Connected';
                app.NotConnectedLamp.Color=[0,1,0];
                app.DigitizeHeadPointsButton.Enable="on";
            else
                app.NotConnectedLampLabel.Text='Not Connected';
                app.NotConnectedLamp.Color=[.1,.1,.1];
                app.DigitizeHeadPointsButton.Enable="off";
            end
            if(strcmp(app.AtlasDropDown.Value,'Colin 27'))
                app.Atlas=nirs.registration.Colin27.mesh_V2;
            end
            UpdateDraw(app);

            app.TformFix=[-0.2501    0.0107   -0.1035   -0.0000;...
                0.0696   -0.5245   -0.1423   -0.0000;...
                0.1240   -0.0240   -0.5847   -0.0000;...
                2.3104    0.9318    1.9328    1.0000];

        end

        % Menu selected function: LoadProbeMenu
        function LoadProbeMenuSelected(app, event)
            [filename, pathname] = uigetfile( ...
       {'*.mat'},'Pick a ProbeInfo.mat file');
            if isequal(filename,0) || isequal(pathname,0)
                return
            end
            probe1020=nirs.io.loadNIRxProbe(fullfile(pathname,filename));
            if(~contains(probe1020.link.Properties.VariableNames,{'type'}))
                probe1020.link.type=ones(height(probe1020.link),1);
            end
            probe1020.link=unique(probe1020.link);
            app.ProbeOriginal=probe1020;
            UpdateDraw(app);
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            if(~isempty(app.COM))
                try;
                    close(app.COM);
                end
            end
        end

        % Value changed function: DigitizeHeadPointsButton
        function DigitizeHeadPointsButtonValueChanged(app, event)
            flush(app.COM);
            writeline(app.COM,'H1,0,0,1');
            flush(app.COM);

            Pts=app.FiducicalsTextArea.UserData;
            dlgh = msgbox({'Click the LPA/RPA/Cz points','(click in air to end)'},'Landmarks','modal');
            firstval=true;
            while(app.DigitizeHeadPointsButton.Value)
                app.DigitizeHeadPointsButton.BackgroundColor='r';
                while(app.COM.NumBytesAvailable==0)
                    pause(.1);
                end
               

                try
                    [xyz,uvw] = getPoints(app);
                    if(firstval)
                         writeline(app.COM,'H1,0,0,0');
                        flush(app.COM);
                        firstval=false;
                    end
                   
                    if(norm(xyz)>25)
                        app.DigitizeHeadPointsButton.Value=0;
                    else
                    Pts(end+1,:)=[xyz; uvw];
                    app.FiducicalsTextArea.Value{end+1}=['Point #' num2str(size(Pts,1)) ' [' num2str(Pts(end,1)) ','...
                        num2str(Pts(end,2)) ',' num2str(Pts(end,3)) ']'];
                    end
                catch
                    disp(lasterr);
                end

            end
            try; close(dlgh); end;
            app.DigitizeHeadPointsButton.BackgroundColor='w';
            if(~isempty(Pts))
                app.FiducicalsTextArea.UserData=Pts;
                registerhead(app);
                UpdateDraw(app);
            end
            app.ResetButton.Visible="on";
            app.ResetButton.Enable="on";
            
            app.PtsRaw.HeadPoints=Pts; % Points digitized to estimate Nasipon/LPA/RPA
             

            if(app.MovetonextCheckBox.Value)
                app.DigitizeOptodesButton.Value=1;
                DigitizeOptodesButtonValueChanged(app);
            end

        end

        % Value changed function: COMPortDropDown
        function COMPortDropDownValueChanged(app, event)
            value = app.COMPortDropDown.Value;
             app.IsConnected = ConnectPolhemus(app);
            if(app.IsConnected)
                app.NotConnectedLampLabel.Text='Connected';
                app.NotConnectedLamp.Color=[0,1,0];
                app.DigitizeHeadPointsButton.Enable="on";
            else
                app.NotConnectedLampLabel.Text='Not Connected';
                app.NotConnectedLamp.Color=[.1,.1,.1];
                app.DigitizeHeadPointsButton.Enable="off";
            end
            if(strcmp(app.AtlasDropDown.Value,'Colin 27'))
                app.Atlas=nirs.registration.Colin27.mesh_V2;
            end
            UpdateDraw(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.FiducicalsTextArea.UserData=[];
            app.FiducicalsTextArea.Value={''};
        end

        % Value changed function: DigitizeOptodesButton
        function DigitizeOptodesButtonValueChanged(app, event)
            
            flush(app.COM);
            writeline(app.COM,'H1,0,0,0');
            flush(app.COM);

            Pts=app.FiducicalsTextArea_2.UserData;

            dlgh = msgbox({'Click the Optodes','(click in air to end)'},'Optodes','modal');


            while(app.DigitizeOptodesButton.Value)
                app.DigitizeOptodesButton.BackgroundColor='r';
                while(app.COM.NumBytesAvailable==0)
                    pause(.1);
                end
                try
                    [xyz,uvw] = getPoints(app);
                   
                    if(norm(xyz)>25)
                        app.DigitizeOptodesButton.Value=0;
                    else
                    Pts(end+1,:)=[xyz; uvw];
                    app.FiducicalsTextArea_2.Value{end+1}=['Point #' num2str(size(Pts,1)) ' [' num2str(Pts(end,1)) ','...
                        num2str(Pts(end,2)) ',' num2str(Pts(end,3)) ']'];
                    pp=app.applyTform(Pts(end,:));
                    drawpoint(app,pp(1:3),[0 0 1]);
                    end
               end

            end
            try; close(dlgh); end;

            app.DigitizeOptodesButton.BackgroundColor='w';
            app.FiducicalsTextArea_2.UserData=Pts;

            registerprobe(app);
            UpdateDraw(app);
            app.ResetButton_2.Visible="on";
            app.ResetButton_2.Enable="on";
            app.PtsRaw.OptodePoints=Pts;

            if(app.MovetonextCheckBox.Value)
                app.DigitizeExtraFiducicialsButton.Value=1;
                DigitizeExtraFiducicialsButtonValueChanged(app);
            end

        end

        % Button pushed function: ResetButton_2
        function ResetButton_2Pushed(app, event)
            app.FiducicalsTextArea_2.UserData=[];
            app.FiducicalsTextArea_2.Value={''};

        end

        % Value changed function: DigitizeExtraFiducicialsButton
        function DigitizeExtraFiducicialsButtonValueChanged(app, event)

            flush(app.COM);
            writeline(app.COM,'H1,0,0,0');

            flush(app.COM);
            
            Pts=app.FiducicalsTextArea_3.UserData;

            while(app.DigitizeExtraFiducicialsButton.Value)
                app.DigitizeExtraFiducicialsButton.BackgroundColor='r';
                while(app.COM.NumBytesAvailable==0)
                    pause(.1);
                end
                try
                    [xyz,uvw] = getPoints(app);
                   
                    if(norm(xyz)>50)
                        app.DigitizeExtraFiducicialsButton.Value=0;
                    else
                        Pts(end+1,:)=[xyz; uvw];
                        app.FiducicalsTextArea_3.Value{end+1}=['Point #' num2str(size(Pts,1)) ' [' num2str(Pts(end,1)) ','...
                            num2str(Pts(end,2)) ',' num2str(Pts(end,3)) ']'];
                        pp=app.applyTform(Pts(end,:));
                        drawpoint(app,pp(1:3),[0 1 0]);
                    end
                end

            end
            app.DigitizeExtraFiducicialsButton.BackgroundColor='w';
            app.FiducicalsTextArea_3.UserData=Pts;
            app.PtsRaw.ExtraPoints=Pts; % Extra Points to use for registration


            app.ResetButton_3.Visible="on";
            app.ResetButton_3.Enable="on";

                h=msgbox('Computing registration');
                registerhead(app);
                UpdateDraw(app);
                delete(h);
             
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.FiducicalsTextArea_3.UserData=[];
            app.FiducicalsTextArea_3.Value={''};
        end

        % Value changed function: DonotrefineheadpointsCheckBox
        function DonotrefineheadpointsCheckBoxValueChanged(app, event)
            registerhead(app);
            UpdateDraw(app);
        end

        % Value changed function: DonotrefinedigitizedpointsCheckBox
        function DonotrefinedigitizedpointsCheckBoxValueChanged(app, event)
            registerhead(app);
            UpdateDraw(app);
        end

        % Value changed function: PointsOnOptodeCapCheckBox
        function PointsOnOptodeCapCheckBoxValueChanged(app, event)
             registerprobe(app);
            UpdateDraw(app);
            
        end

        % Value changed function: DitigizerErrorDistanceEditField
        function DitigizerErrorDistanceEditFieldValueChanged(app, event)
            registerprobe(app);
            UpdateDraw(app);
        end

        % Value changed function: ShowRawMeasurementPointsCheckBox
        function ShowRawMeasurementPointsCheckBoxValueChanged(app, event)
            UpdateDraw(app);
        end

        % Callback function: SaveDataButton, SaveRegistrationMenu
        function SaveData(app, event)
            data=struct;
            data.HeadPoints=app.HeadPoints; % Points digitized to estimate Nasipon/LPA/RPA
            
            app.OptodePoints=app.applyTform(app.FiducicalsTextArea_2.UserData); 
            data.OptodePoints=app.OptodePoints; % Optode Positions (as digitized)
            
            app.ExtraPoints=app.applyTform(app.FiducicalsTextArea_3.UserData); 
            data.ExtraPoints=app.ExtraPoints; % Extra Points to use for registration
            data.Atlas=app.Atlas; % Atlas Head model selected
            data.ProbeOriginal=app.ProbeOriginal; % Template probe
            data.ProbeRegistered=app.ProbeRegistered; % Registered Probe
            data.PtsRegistered=app.PtsRegistered;
            data.PtsRaw=app.PtsRaw;
            data.Tform=app.Tform;
            filename=['NIRS_Registration_ ' datestr(now) '.mat'];
            filename=strrep(filename,' ','_');
            filename=strrep(filename,':','_');
            [filename, pathname] = uiputfile(filename, 'Save Registration as');

            if(~isempty(filename))
                save(fullfile(pathname,filename),'-STRUCT','data');
                disp(['Data saved as: ' fullfile(pathname,filename)]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadProbeMenu = uimenu(app.FileMenu);
            app.LoadProbeMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadProbeMenuSelected, true);
            app.LoadProbeMenu.Enable = 'off';
            app.LoadProbeMenu.Text = 'Load Probe';
            app.SaveRegistrationMenu = uimenu(app.FileMenu);
            app.SaveRegistrationMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveRegistrationMenu.Text = 'Save Registration';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DigitizingTab = uitab(app.TabGroup);
            app.DigitizingTab.Title = 'Digitizing';
            app.FiducicalsTextArea = uitextarea(app.DigitizingTab);
            app.DigitizeHeadPointsButton = uibutton(app.DigitizingTab, 'state');
            app.DigitizeHeadPointsButton.ValueChangedFcn = createCallbackFcn(app, @DigitizeHeadPointsButtonValueChanged, true);
            app.DigitizeHeadPointsButton.Text = 'Digitize Head Points';
            app.FiducicalsTextArea_2 = uitextarea(app.DigitizingTab);
            app.FiducicalsTextArea_2.Enable = 'off';
            app.DigitizeOptodesButton = uibutton(app.DigitizingTab, 'state');
            app.DigitizeOptodesButton.ValueChangedFcn = createCallbackFcn(app, @DigitizeOptodesButtonValueChanged, true);
            app.DigitizeOptodesButton.Enable = 'off';
            app.DigitizeOptodesButton.Text = 'Digitize Optodes';
            app.FiducicalsTextArea_3 = uitextarea(app.DigitizingTab);
            app.FiducicalsTextArea_3.Enable = 'off';
            app.DigitizeExtraFiducicialsButton = uibutton(app.DigitizingTab, 'state');
            app.DigitizeExtraFiducicialsButton.ValueChangedFcn = createCallbackFcn(app, @DigitizeExtraFiducicialsButtonValueChanged, true);
            app.DigitizeExtraFiducicialsButton.Enable = 'off';
            app.DigitizeExtraFiducicialsButton.Text = 'Digitize Extra Fiducicials';
            app.ResetButton = uibutton(app.DigitizingTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.ResetButton_2 = uibutton(app.DigitizingTab, 'push');
            app.ResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_2Pushed, true);
            app.ResetButton_2.Enable = 'off';
            app.ResetButton_2.Visible = 'off';
            app.ResetButton_2.Text = 'Reset';
            app.ResetButton_3 = uibutton(app.DigitizingTab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Enable = 'off';
            app.ResetButton_3.Visible = 'off';
            app.ResetButton_3.Text = 'Reset';
            app.RegistrationTab = uitab(app.TabGroup);
            app.RegistrationTab.Title = 'Registration';
            app.HeadSizePanel = uipanel(app.RegistrationTab);
            app.HeadSizePanel.Enable = 'off';
            app.HeadSizePanel.Title = 'Head Size';
            app.CircumfernceEditFieldLabel = uilabel(app.HeadSizePanel);
            app.CircumfernceEditFieldLabel.Text = 'Circumfernce';
            app.CircumfernceEditField = uieditfield(app.HeadSizePanel, 'numeric');
            app.CircumfernceEditField.Limits = [0 Inf];
            app.CircumfernceEditField.Value = 527.7821;
            app.UseMeasuredValueCheckBox = uicheckbox(app.HeadSizePanel);
            app.UseMeasuredValueCheckBox.Text = 'Use Measured Value';
            app.NasionCzInionEditFieldLabel = uilabel(app.HeadSizePanel);
            app.NasionCzInionEditFieldLabel.Text = 'Nasion-Cz-Inion';
            app.NasionCzInionEditField = uieditfield(app.HeadSizePanel, 'numeric');
            app.NasionCzInionEditField.Limits = [0 Inf];
            app.NasionCzInionEditField.Value = 346.8096;
            app.LPACZRPAEditFieldLabel = uilabel(app.HeadSizePanel);
            app.LPACZRPAEditFieldLabel.Text = 'LPA-CZ-RPA';
            app.LPACZRPAEditField = uieditfield(app.HeadSizePanel, 'numeric');
            app.LPACZRPAEditField.Limits = [0 Inf];
            app.LPACZRPAEditField.Value = 325.7175;
            app.ProbeRegistrationPanel = uipanel(app.RegistrationTab);
            app.ProbeRegistrationPanel.Enable = 'off';
            app.ProbeRegistrationPanel.Title = 'Probe Registration';
            app.DonotrefinedigitizedpointsCheckBox = uicheckbox(app.ProbeRegistrationPanel);
            app.DonotrefinedigitizedpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DonotrefinedigitizedpointsCheckBoxValueChanged, true);
            app.DonotrefinedigitizedpointsCheckBox.Text = 'Do not refine digitized points';
            app.DitigizerErrorDistanceEditFieldLabel = uilabel(app.ProbeRegistrationPanel);
            app.DitigizerErrorDistanceEditFieldLabel.Text = 'Ditigizer Error Distance';
            app.DitigizerErrorDistanceEditField = uieditfield(app.ProbeRegistrationPanel, 'numeric');
            app.DitigizerErrorDistanceEditField.Limits = [0 Inf];
            app.DitigizerErrorDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @DitigizerErrorDistanceEditFieldValueChanged, true);
            app.DitigizerErrorDistanceEditField.Value = 5;
            app.DonotrefineheadpointsCheckBox = uicheckbox(app.ProbeRegistrationPanel);
            app.DonotrefineheadpointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @DonotrefineheadpointsCheckBoxValueChanged, true);
            app.DonotrefineheadpointsCheckBox.Text = 'Do not refine head points';
            app.DonotrefineheadpointsCheckBox.Value = true;
            app.PointsOnOptodeCapCheckBox = uicheckbox(app.ProbeRegistrationPanel);
            app.PointsOnOptodeCapCheckBox.ValueChangedFcn = createCallbackFcn(app, @PointsOnOptodeCapCheckBoxValueChanged, true);
            app.PointsOnOptodeCapCheckBox.Text = 'Points On Optode Cap';
            app.PointsOnOptodeCapCheckBox.Value = true;
            app.OptodeCapOffsetEditFieldLabel = uilabel(app.ProbeRegistrationPanel);
            app.OptodeCapOffsetEditFieldLabel.Text = 'Optode Cap Offset';
            app.OptodeCapOffsetEditField = uieditfield(app.ProbeRegistrationPanel, 'numeric');
            app.OptodeCapOffsetEditField.Limits = [0 Inf];
            app.OptodeCapOffsetEditField.Value = 16.7;
            app.HeadmodelPanel = uipanel(app.RegistrationTab);
            app.HeadmodelPanel.Enable = 'off';
            app.HeadmodelPanel.Title = 'Head model';
            app.AtlasDropDownLabel = uilabel(app.HeadmodelPanel);
            app.AtlasDropDownLabel.Enable = 'off';
            app.AtlasDropDownLabel.Text = 'Atlas';
            app.AtlasDropDown = uidropdown(app.HeadmodelPanel);
            app.AtlasDropDown.Items = {'Colin 27'};
            app.AtlasDropDown.Enable = 'off';
            app.AtlasDropDown.Value = 'Colin 27';
            app.PolhemusTab = uitab(app.TabGroup);
            app.PolhemusTab.Title = 'Polhemus';
            app.HardwareSetupPanel = uipanel(app.PolhemusTab);
            app.HardwareSetupPanel.Title = 'Hardware Setup';
            app.HemmisphereDropDownLabel = uilabel(app.HardwareSetupPanel);
            app.HemmisphereDropDownLabel.Enable = 'off';
            app.HemmisphereDropDownLabel.Text = 'Hemmisphere';
            app.HemmisphereDropDown = uidropdown(app.HardwareSetupPanel);
            app.HemmisphereDropDown.Items = {'Z-', 'Z+', 'X-', 'X+', 'Y-', 'Y+'};
            app.HemmisphereDropDown.Enable = 'off';
            app.HemmisphereDropDown.Value = 'Z-';
            app.Sensor1DropDownLabel = uilabel(app.HardwareSetupPanel);
            app.Sensor1DropDownLabel.Enable = 'off';
            app.Sensor1DropDownLabel.Text = 'Sensor 1';
            app.Sensor1DropDown = uidropdown(app.HardwareSetupPanel);
            app.Sensor1DropDown.Items = {'Stylus', 'Floating Sensor', 'None'};
            app.Sensor1DropDown.Enable = 'off';
            app.Sensor1DropDown.Value = 'Stylus';
            app.Sensor2DropDownLabel = uilabel(app.HardwareSetupPanel);
            app.Sensor2DropDownLabel.Enable = 'off';
            app.Sensor2DropDownLabel.Text = 'Sensor 2';
            app.Sensor2DropDown = uidropdown(app.HardwareSetupPanel);
            app.Sensor2DropDown.Items = {'Stylus', 'Floating Sensor', 'None'};
            app.Sensor2DropDown.Enable = 'off';
            app.Sensor2DropDown.Value = 'None';
            app.CommunicationPanel = uipanel(app.PolhemusTab);
            app.CommunicationPanel.Title = 'Communication';
            app.DeviceIDEditFieldLabel = uilabel(app.CommunicationPanel);
            app.DeviceIDEditFieldLabel.Text = 'Device ID';
            app.DeviceIDEditField = uieditfield(app.CommunicationPanel, 'text');
            app.DeviceIDEditField.Editable = 'off';
            app.FirmwareVersionEditFieldLabel = uilabel(app.CommunicationPanel);
            app.FirmwareVersionEditFieldLabel.Text = 'Firmware Version';
            app.FirmwareVersionEditField = uieditfield(app.CommunicationPanel, 'text');
            app.FirmwareVersionEditField.Editable = 'off';
            app.COMPortDropDownLabel = uilabel(app.CommunicationPanel);
            app.COMPortDropDownLabel.Text = 'COM Port';
            app.COMPortDropDown = uidropdown(app.CommunicationPanel);
            app.COMPortDropDown.ValueChangedFcn = createCallbackFcn(app, @COMPortDropDownValueChanged, true);
            app.NotConnectedLampLabel = uilabel(app.CommunicationPanel);
            app.NotConnectedLampLabel.Text = 'Not Connected';
            app.NotConnectedLamp = uilamp(app.CommunicationPanel);
            app.ShowRawMeasurementPointsCheckBox = uicheckbox(app.UIFigure);
            app.ShowRawMeasurementPointsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowRawMeasurementPointsCheckBoxValueChanged, true);
            app.ShowRawMeasurementPointsCheckBox.Text = 'Show Raw Measurement Points';
            app.ShowRawMeasurementPointsCheckBox.Value = true;
            app.MovetonextCheckBox = uicheckbox(app.UIFigure);
            app.MovetonextCheckBox.Text = 'Move to next ';
            app.MovetonextCheckBox.Value = true;
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveData, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NIRS_Digitizer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end